classdef fem4mat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ProblemMenu               matlab.ui.container.Menu
        FEMMenu                   matlab.ui.container.Menu
        EstimateMenu              matlab.ui.container.Menu
        SolverMenu                matlab.ui.container.Menu
        RefineMenu                matlab.ui.container.Menu
        SolveButton               matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        MeshTab                   matlab.ui.container.Tab
        SolutionTab               matlab.ui.container.Tab
        ConvergenceTab            matlab.ui.container.Tab
        ParameterTab              matlab.ui.container.Tab
        MaxNoLvlEditField         matlab.ui.control.NumericEditField
        MaxNoLvlEditFieldLabel    matlab.ui.control.Label
        MaxNoDoFsEditField        matlab.ui.control.NumericEditField
        MaxNoDoFsEditFieldLabel   matlab.ui.control.Label
        Parameter4EditField       matlab.ui.control.NumericEditField
        Parameter4EditFieldLabel  matlab.ui.control.Label
        Parameter3EditField       matlab.ui.control.NumericEditField
        Parameter3EditFieldLabel  matlab.ui.control.Label
        Parameter2EditField       matlab.ui.control.NumericEditField
        Parameter2EditFieldLabel  matlab.ui.control.Label
        Parameter1EditField       matlab.ui.control.NumericEditField
        Parameter1EditFieldLabel  matlab.ui.control.Label
    end

    % Copyright (c) 2026 by the fem4mat authors
    
    properties (Access = public)
        mesh
        fe
        x
        maxNrDoF
        maxLvl
        modules
        moduleIndx = 1
        feIndx = 1
        refineIndx = 1
        problemIndx = 1
        estimateIndx = 1
        solverIndx = 1
        meshAxes
        solutionAxes
        convergenceAxes
    end
    
    methods (Access = public)

        function plotMesh(app,bdry)
            if nargin < 2
                bdry = false;
            end
            delete(app.meshAxes);
            figure(1);
            clf;
            plot(app.mesh);
            if bdry
                hold on;
                app.mesh.plotBd;
            end
            app.meshAxes = copyobj(gca, app.MeshTab);
            close(figure(1));
            drawnow;
        end

        function loadGeometry(app)
            app.mesh = app.modules{app.moduleIndx}.loadGeometry(app.problemIndx);
            app.plotMesh(true);
        end

        function afem(app,maxLvl)
            if nargin < 2
                app.maxLvl = inf;
            else
                app.maxLvl = maxLvl;
            end
            if isempty(app.mesh)
                app.loadGeometry();
            end
            app.modules{app.moduleIndx}.afem(app);
            %plot solution
            delete(app.solutionAxes);
            figure(2);
            clf;
            app.modules{app.moduleIndx}.plot(app.problemIndx);
            app.solutionAxes = copyobj(gca, app.SolutionTab);
            close(figure(2));
            if app.maxLvl > 1
                %plot convergence
                delete(app.convergenceAxes);
                figure(3);
                clf;
                app.modules{app.moduleIndx}.drawConvergence(true,true,true);
                app.convergenceAxes = copyobj(gca, app.ConvergenceTab);
                close(figure(3));
            end
        end
        
        function app = loadModules(app)
            app.modules = {};
            path = mfilename('fullpath');
            k = strfind(mfilename('fullpath'),mfilename('class'));
            path = path(1:k(end)-2);
            files = what(path);
            files = files.m;
            for k = 1 : numel(files)
                if ~isempty(strfind(files{k},[mfilename('class'),'Module']))...
                        && ~strcmp(files{k},[mfilename('class'),'ModuleTemplate.m'])
                    str = files{k};
                    app.modules{end+1} = eval([str(1:end-2),'()']);
                end
            end
        end

        function app = createMenu(app)
            delete(app.ProblemMenu.Children);
            delete(app.FEMMenu.Children);
            delete(app.EstimateMenu.Children);
            delete(app.SolverMenu.Children);
            delete(app.RefineMenu.Children);

            for k = 1 : numel(app.modules)
                listItem = app.modules{k}.title;
                mitem = uimenu(app.ProblemMenu,'Text',['&',listItem]);
                for j = 1 : numel(app.modules{k}.problems)
                    item = app.modules{k}.problems{j};
                    if app.moduleIndx == k && app.problemIndx == j
                        m = uimenu(mitem,'Text',['&',item],'Checked','on');
                    else
                        m = uimenu(mitem,'Text',['&',item]);
                    end
                    m.MenuSelectedFcn = @(~,~)app.changeMenu('Problem',k,j);
                end
            end

            for j = 1 : numel(app.modules{app.moduleIndx}.finiteElements)
                item = app.modules{app.moduleIndx}.finiteElements{j};
                if app.feIndx == j
                    m = uimenu(app.FEMMenu,'Text',['&',item],'Checked','on');
                else
                    m = uimenu(app.FEMMenu,'Text',['&',item]);
                end
                m.MenuSelectedFcn = @(~,~)app.changeMenu('FE',j);
            end

            for j = 1 : numel(app.modules{app.moduleIndx}.estimate)
                item = app.modules{app.moduleIndx}.estimate{j};
                if app.estimateIndx == j
                    m = uimenu(app.EstimateMenu,'Text',['&',item],'Checked','on');
                else
                    m = uimenu(app.EstimateMenu,'Text',['&',item]);
                end
                m.MenuSelectedFcn = @(~,~)app.changeMenu('ESTIMATE',j);
            end

            for j = 1 : numel(app.modules{app.moduleIndx}.solver)
                item = app.modules{app.moduleIndx}.solver{j};
                if app.solverIndx == j
                    m = uimenu(app.SolverMenu,'Text',['&',item],'Checked','on');
                else
                    m = uimenu(app.SolverMenu,'Text',['&',item]);
                end
                m.MenuSelectedFcn = @(~,~)app.changeMenu('SOLVER',j);
            end

            for j = 1 : numel(app.modules{app.moduleIndx}.refine)
                item = app.modules{app.moduleIndx}.refine{j};
                if app.refineIndx == j
                    m = uimenu(app.RefineMenu,'Text',['&',item],'Checked','on');
                else
                    m = uimenu(app.RefineMenu,'Text',['&',item]);
                end
                m.MenuSelectedFcn = @(~,~)app.changeMenu('REFINE',j);
            end
        end

        function changeMenu(app,type,j,k)
            if strcmpi(type,'FE')
                app.feIndx = j;
            elseif strcmpi(type,'REFINE')
                app.refineIndx = j;
            elseif strcmpi(type,'ESTIMATE')
                app.estimateIndx = j;
            elseif strcmpi(type,'SOLVER')
                app.solverIndx = j;
            elseif strcmpi(type,'PROBLEM')
                if app.moduleIndx ~= j
                    app.feIndx = 1;
                    app.refineIndx = 1;
                    app.estimateIndx = 1;
                    app.solverIndx = 1;
                    app.moduleIndx = j;
                end
                app.problemIndx = k;
                app.loadGeometry();
            else
                error(['Popup type ',type,' unknown!']);
            end
            app.createMenu();
            app.createParameters();
        end

        function createParameters(app)
            nrParam = numel(app.modules{app.moduleIndx}.parameter);
            if nrParam < 1
                app.Parameter1EditFieldLabel.Visible = 'off';
                app.Parameter1EditField.Visible = 'off';
            else
                app.Parameter1EditFieldLabel.Visible = 'on';
                app.Parameter1EditField.Visible = 'on';
                app.Parameter1EditFieldLabel.Text = app.modules{app.moduleIndx}.parameter{1};
                app.Parameter1EditField.Value = app.modules{app.moduleIndx}.(app.modules{app.moduleIndx}.parameter{1});
            end
            if nrParam < 2
                app.Parameter2EditFieldLabel.Visible = 'off';
                app.Parameter2EditField.Visible = 'off';
            else
                app.Parameter2EditFieldLabel.Visible = 'on';
                app.Parameter2EditField.Visible = 'on';
                app.Parameter2EditFieldLabel.Text = app.modules{app.moduleIndx}.parameter{2};
                app.Parameter2EditField.Value = app.modules{app.moduleIndx}.(app.modules{app.moduleIndx}.parameter{2});
            end
            if nrParam < 3
                app.Parameter3EditFieldLabel.Visible = 'off';
                app.Parameter3EditField.Visible = 'off';
            else
                app.Parameter3EditFieldLabel.Visible = 'on';
                app.Parameter3EditField.Visible = 'on';
                app.Parameter3EditFieldLabel.Text = app.modules{app.moduleIndx}.parameter{3};
                app.Parameter3EditField.Value = app.modules{app.moduleIndx}.(app.modules{app.moduleIndx}.parameter{3});
            end
            if nrParam < 4
                app.Parameter4EditFieldLabel.Visible = 'off';
                app.Parameter4EditField.Visible = 'off';
            else
                app.Parameter4EditFieldLabel.Visible = 'on';
                app.Parameter4EditField.Visible = 'on';
                app.Parameter4EditFieldLabel.Text = app.modules{app.moduleIndx}.parameter{4};
                app.Parameter4EditField.Value = app.modules{app.moduleIndx}.(app.modules{app.moduleIndx}.parameter{4});
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load modules
            app.loadModules();
            % create Menu
            app.createMenu();
            app.createParameters();
            % default parameters
            app.maxLvl = app.MaxNoLvlEditField.Value;
            app.maxNrDoF = app.MaxNoDoFsEditField.Value;
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            app.afem();
        end

        % Value changed function: MaxNoDoFsEditField
        function MaxNoDoFsEditFieldValueChanged(app, event)
            app.maxNrDoF = app.MaxNoDoFsEditField.Value;
        end

        % Value changed function: MaxNoLvlEditField
        function MaxNoLvlEditFieldValueChanged(app, event)
            app.maxLvl = app.MaxNoLvlEditField.Value;
        end

        % Value changed function: Parameter1EditField
        function Parameter1EditFieldValueChanged(app, event)
            app.modules{app.moduleIndx}.(app.modules{app.moduleIndx}.parameter{1}) = app.Parameter1EditField.Value;
        end

        % Value changed function: Parameter2EditField
        function Parameter2EditFieldValueChanged(app, event)
            app.modules{app.moduleIndx}.(app.modules{app.moduleIndx}.parameter{2}) = app.Parameter2EditField.Value;
        end

        % Value changed function: Parameter3EditField
        function Parameter3EditFieldValueChanged(app, event)
            app.modules{app.moduleIndx}.(app.modules{app.moduleIndx}.parameter{3}) = app.Parameter3EditField.Value;
        end

        % Value changed function: Parameter4EditField
        function Parameter4EditFieldValueChanged(app, event)
            app.modules{app.moduleIndx}.(app.modules{app.moduleIndx}.parameter{4}) = app.Parameter4EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProblemMenu = uimenu(app.UIFigure);
            app.ProblemMenu.Text = 'Problem';
            app.FEMMenu = uimenu(app.UIFigure);
            app.FEMMenu.Text = 'FEM';
            app.EstimateMenu = uimenu(app.UIFigure);
            app.EstimateMenu.Text = 'Estimate';
            app.SolverMenu = uimenu(app.UIFigure);
            app.SolverMenu.Text = 'Solver';
            app.RefineMenu = uimenu(app.UIFigure);
            app.RefineMenu.Text = 'Refine';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MeshTab = uitab(app.TabGroup);
            app.MeshTab.Title = 'Mesh';
            app.SolutionTab = uitab(app.TabGroup);
            app.SolutionTab.Title = 'Solution';
            app.ConvergenceTab = uitab(app.TabGroup);
            app.ConvergenceTab.Title = 'Convergence';
            app.ParameterTab = uitab(app.TabGroup);
            app.ParameterTab.Title = 'Parameter';
            app.Parameter1EditFieldLabel = uilabel(app.ParameterTab);
            app.Parameter1EditFieldLabel.Text = 'Parameter1';
            app.Parameter1EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Parameter1EditField.ValueChangedFcn = createCallbackFcn(app, @Parameter1EditFieldValueChanged, true);
            app.Parameter2EditFieldLabel = uilabel(app.ParameterTab);
            app.Parameter2EditFieldLabel.Text = 'Parameter2';
            app.Parameter2EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Parameter2EditField.ValueChangedFcn = createCallbackFcn(app, @Parameter2EditFieldValueChanged, true);
            app.Parameter3EditFieldLabel = uilabel(app.ParameterTab);
            app.Parameter3EditFieldLabel.Text = 'Parameter3';
            app.Parameter3EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Parameter3EditField.ValueChangedFcn = createCallbackFcn(app, @Parameter3EditFieldValueChanged, true);
            app.Parameter4EditFieldLabel = uilabel(app.ParameterTab);
            app.Parameter4EditFieldLabel.Text = 'Parameter4';
            app.Parameter4EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Parameter4EditField.ValueChangedFcn = createCallbackFcn(app, @Parameter4EditFieldValueChanged, true);
            app.MaxNoDoFsEditFieldLabel = uilabel(app.ParameterTab);
            app.MaxNoDoFsEditFieldLabel.Text = 'Max No DoFs';
            app.MaxNoDoFsEditField = uieditfield(app.ParameterTab, 'numeric');
            app.MaxNoDoFsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxNoDoFsEditFieldValueChanged, true);
            app.MaxNoDoFsEditField.Value = 10000;
            app.MaxNoLvlEditFieldLabel = uilabel(app.ParameterTab);
            app.MaxNoLvlEditFieldLabel.Text = 'Max No Lvl';
            app.MaxNoLvlEditField = uieditfield(app.ParameterTab, 'numeric');
            app.MaxNoLvlEditField.ValueChangedFcn = createCallbackFcn(app, @MaxNoLvlEditFieldValueChanged, true);
            app.MaxNoLvlEditField.Value = 10;
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fem4mat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end