classdef plotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        graphTab                    matlab.ui.container.Tab
        graphListBox                matlab.ui.control.ListBox
        ListBoxLabel                matlab.ui.control.Label
        tableTab                    matlab.ui.container.Tab
        tableListBox                matlab.ui.control.ListBox
        Label                       matlab.ui.control.Label
        efficiencyTab               matlab.ui.container.Tab
        effNominatorListBoxLabel    matlab.ui.control.Label
        effDenominatorListBoxLabel  matlab.ui.control.Label
        effDenominatorListBox       matlab.ui.control.ListBox
        effNominatorListBox         matlab.ui.control.ListBox
        TiKzTab                     matlab.ui.container.Tab
        TriangleButtonGroup         matlab.ui.container.ButtonGroup
        BottomButton                matlab.ui.control.RadioButton
        TopButton                   matlab.ui.control.RadioButton
        NoneButton                  matlab.ui.control.RadioButton
        latexfolderEditField        matlab.ui.control.EditField
        latexfolderEditFieldLabel   matlab.ui.control.Label
        filenameEditField           matlab.ui.control.EditField
        filenameEditFieldLabel      matlab.ui.control.Label
        LaTeXCheckBox               matlab.ui.control.CheckBox
        figurePanel                 matlab.ui.container.Panel
        fignoEditField              matlab.ui.control.NumericEditField
        fignoEditFieldLabel         matlab.ui.control.Label
        removelvlEditField          matlab.ui.control.NumericEditField
        removelvlEditFieldLabel     matlab.ui.control.Label
        minDoFEditField             matlab.ui.control.NumericEditField
        minDoFEditFieldLabel        matlab.ui.control.Label
        rateLabel                   matlab.ui.control.Label
        legendEditField             matlab.ui.control.EditField
        legendEditFieldLabel        matlab.ui.control.Label
        gridoffCheckBox             matlab.ui.control.CheckBox
        LoadPanel                   matlab.ui.container.Panel
        fem4matLabel                matlab.ui.control.Label
        folderButton                matlab.ui.control.Button
        loadedLamp                  matlab.ui.control.Lamp
        loadedLampLabel             matlab.ui.control.Label
        textDoFLabel                matlab.ui.control.Label
        pathEditField               matlab.ui.control.EditField
        pathEditFieldLabel          matlab.ui.control.Label
        showPanel                   matlab.ui.container.Panel
        TiKzefficiencyButton        matlab.ui.control.Button
        TiKzButton                  matlab.ui.control.Button
        tabularButton               matlab.ui.control.Button
        efficiencyButton            matlab.ui.control.Button
        baseButton                  matlab.ui.control.Button
        showButton                  matlab.ui.control.Button
        filenamePanel               matlab.ui.container.Panel
        resetButton                 matlab.ui.control.Button
        maxNrDoFDropDown            matlab.ui.control.DropDown
        maxNrDoFDropDownLabel       matlab.ui.control.Label
        solverDropDown              matlab.ui.control.DropDown
        solverDropDownLabel         matlab.ui.control.Label
        param2DropDown              matlab.ui.control.DropDown
        param2DropDownLabel         matlab.ui.control.Label
        param1DropDown              matlab.ui.control.DropDown
        param1DropDownLabel         matlab.ui.control.Label
        estimatorDropDown           matlab.ui.control.DropDown
        estimatorDropDownLabel      matlab.ui.control.Label
        problemDropDown             matlab.ui.control.DropDown
        problemDropDownLabel        matlab.ui.control.Label
        feDropDown                  matlab.ui.control.DropDown
        feDropDownLabel             matlab.ui.control.Label
        refineDropDown              matlab.ui.control.DropDown
        refineDropDownLabel         matlab.ui.control.Label
    end

    %plotApp - Plot convergence histories of saved fem4mat results
    %   plotApp lists the results files *.mat in a folder, by default
    %   fem4matPath.resultFolder, as saved by fem4matResult. The eight parts of
    %   their names problem_fe_refine_estimator_solver_param1_param2_maxNrDoF
    %   fill the eight dropdowns, labelled by p.label. param1 and param2 are
    %   shown as numbers (0p3 as 0.3), and the trailing zeros of maxNrDoF
    %   as an exponent (150000 as 15e4).
    %
    %   The fields of p.level of the selected file can be plotted against
    %   nrDoF or another field, listed in a table, or divided into
    %   efficiencies. The curves can be exported to a TikZ file based on
    %   plotAppTikZ.tex, with their data in data/*.dat, which mat2data can
    %   rebuild. The optional fields p.errorlabel, p.tikzlabel and p.draw
    %   set the legend texts and line styles.
    %
    %   See also fem4matResult, mat2data, fem4matPath
    
    % Copyright (c) 2026 by the fem4mat authors
    
    properties (SetAccess=private, GetAccess=public)
        dropDownNames = {'problem','fe','refine','estimator','solver','param1','param2','maxNrDoF'};
        labelDefault = {'problem','fe','refine','estimator','solver','','','maxNrDoF'}; % default labels
        p % Data structure
        label % label modified from p
        files % data of all loadable files from current directory
        currentGraph % keep current graph active for new files
        currentTable % keep current table items active for new files
        currentEffNominator % keep current eff nominator items active for new files
        currentEffDenominator % keep current eff denominator items active for new files
        currentBase = 'nrDoF'% plot as x-axis
        fileNames % file names without extension, one per row of files
    end
    
    methods (Access = private)
        
        % read all mat files and create the files-matrix and modify the
        % default labels
        function readFiles(app)
            stringFolder = app.pathEditField.Value;
            app.files = what(fullfile(stringFolder));
            app.files = app.files.mat;
            app.label = app.labelDefault;
            if ~isempty(app.files)
                q = load(fullfile(stringFolder,app.files{1}),'p');
                q = q.p;
                if isfield(q,'label')
                    if numel(q.label) <= 2
                        shift = 5;
                    else
                        shift = 0;
                    end
                    for k = 1 : numel(q.label)
                        if iscell(q.label{k})
                            app.label{shift+k} = cell2mat(q.label{k});
                        else
                            app.label{shift+k} = q.label{k};
                        end
                    end
                end
                app.files = replace(app.files,'.mat','');
                app.fileNames = app.files;
                app.files = cellfun(@(s)strsplit(s,'_'),app.files,'UniformOutput',false);
                app.files = reshape([app.files{:}],length(app.files{1}),[])';
                app.files = fem4matResult.displayParts(app.files);
            end
            for i = 1 : numel(app.dropDownNames)
                app.([app.dropDownNames{i},'DropDownLabel']).Text = app.label{i};
            end
        end
        
        % load a file if exists
        function loadFile(app)
            app.loadedLamp.Color = 'r';
            app.graphListBox.Items = {};
            app.tableListBox.Items = {};
            app.effNominatorListBox.Items = {};
            app.effDenominatorListBox.Items = {};
            app.p = [];
            if ~isempty(app.problemDropDown.Items)
                filename = [generateFilename(app),'.mat'];
                dir = app.pathEditField.Value;
                if ~isempty(filename) & exist(fullfile(dir,filename),'file')==2
                    data = load(fullfile(dir,filename),'p');
                    app.p = data.p;
                    for curLvl = 1 : length(app.p.level)
                        app.p.level(curLvl).level = curLvl;
                        app.p.level(curLvl).level0 = curLvl-1;
                        app.p.level(curLvl).n4DoF2sqrt = sqrt(app.p.level(curLvl).nrDoF);
                    end
                    list = fieldnames(app.p.level);
                    app.graphListBox.Items = list;
                    app.tableListBox.Items = list;
                    app.effNominatorListBox.Items = list;
                    app.effDenominatorListBox.Items = list;
                    app.selectCurrentGraph;
                    app.selectCurrentTable;
                    app.selectCurrentEffNominator;
                    app.selectCurrentEffDenominator;
                    app.textDoFLabel.Text = ['DOF: ',int2str(app.p.level(end).nrDoF)];
                    app.loadedLamp.Color = 'g';
                end
            end
        end
        
        % create the filename string as combination of the option fields
        function filename = generateFilename(app)
            values = cellfun(@(f)app.([f,'DropDown']).Value,app.dropDownNames,'UniformOutput',false);
            filename = [];
            if ~any(cellfun(@isempty,values))
                row = find(all(strcmp(app.files,repmat(values,size(app.files,1),1)),2),1);
                if ~isempty(row)
                    filename = app.fileNames{row};
                end
            end
        end
        
        % select the current graph
        function selectCurrentGraph(app)
            list = app.graphListBox.Items;
            isMem = cellfun(@(z)(strcmp(z,app.currentGraph)),list,'UniformOutput',false);
            if any(cell2mat(isMem))
                app.graphListBox.Value = app.currentGraph;
            elseif ~isempty(app.graphListBox.Items)
                app.graphListBox.Value = app.graphListBox.Items{1};
                app.currentGraph = app.graphListBox.Value;
            end
        end
        
        % select the current table errors
        function selectCurrentTable(app)
            list = app.tableListBox.Items;
            isMem = cellfun(@(z)(strcmp(z,app.currentTable)),list,'UniformOutput',false);
            if sum(cell2mat(isMem)) > 0 && sum(cell2mat(isMem))==numel(app.currentTable)
                app.tableListBox.Value = app.currentTable;
            end
        end

        % select the current efficiency nominator
        function selectCurrentEffNominator(app)
            list = app.effNominatorListBox.Items;
            isMem = cellfun(@(z)(strcmp(z,app.currentEffNominator)),list,'UniformOutput',false);
            if any(cell2mat(isMem))
                app.effNominatorListBox.Value = app.currentEffNominator;
            elseif ~isempty(app.effNominatorListBox.Items)
                app.effNominatorListBox.Value = app.effNominatorListBox.Items{1};
                app.currentEffNominator = app.effNominatorListBox.Value;
            end
        end

        % select the current efficiency denominator
        function selectCurrentEffDenominator(app)
            list = app.effDenominatorListBox.Items;
            isMem = cellfun(@(z)(strcmp(z,app.currentEffDenominator)),list,'UniformOutput',false);
            if any(cell2mat(isMem))
                app.effDenominatorListBox.Value = app.currentEffDenominator;
            elseif ~isempty(app.effDenominatorListBox.Items)
                app.effDenominatorListBox.Value = app.effDenominatorListBox.Items{1};
                app.currentEffDenominator = app.effDenominatorListBox.Value;
            end
        end
        
        % check the selected folder
        function checkFolder(app)
            folder = app.pathEditField.Value;
            if ~exist(folder,'dir')
                app.pathEditField.BackgroundColor = 'r';  
                app.loadedLamp.Color = 'r';
                app.graphListBox.Items = {};
                app.refineDropDown.Items = {};
                app.feDropDown.Items = {};
                app.maxNrDoFDropDown.Items = {};
                app.problemDropDown.Items = {};
                app.estimatorDropDown.Items = {};
                app.param1DropDown.Items = {};
                app.param2DropDown.Items = {};
            else
                app.pathEditField.BackgroundColor = 'w';
                app.readFiles;
                app.resetDropDown;
                app.loadFile;
            end
        end

        % update tikz legends automatically iff 'auto entry = on'
        function lines = tikzUpdateLegend(app,lines)
            indx = find(contains(lines,'auto entry = on'));
            data = lines(indx-1);
            data = replace(data,'Eff_','');
            data = replace(data,'.dat};','');
            data = replace(data,'  table {data/','');
            data = cellfun(@(z)split(z,'--'),data,'UniformOutput',false);
            data = cellfun(@(z)z{1},data,'UniformOutput',false);
            data = cellfun(@(z)split(z,'_'),data,'UniformOutput',false);
            data = reshape([data{:}],[],numel(data));
            data(1:8,:) = fem4matResult.displayParts(data(1:8,:)')';
            isLabel = zeros(size(data,1)-1,1);
            for j = 1 : size(data,1)
                isLabel(j) = numel(unique(data(j,:)))>1;
            end
            isLabel(end-1) = 0; %ignore maxNrDoF
            for k = 1:numel(indx)
                j = indx(k);
                legend = [];
                sep = [];
                for i = 1 : numel(isLabel)-1
                    if isLabel(i)
                        if isfield(app.p,'tikzlabel') && numel(app.p.tikzlabel)>=i
                            if isempty(app.p.tikzlabel{i})
                                legend = [legend,sep,data{i,k}]; %#ok<AGROW>
                            else
                                if contains(app.p.tikzlabel{i},'_$')
                                    legend = [legend,sep,'$',strrep(app.p.tikzlabel{i},'$',''),'{',data{i,k},'}$']; %#ok<AGROW>
                                elseif contains(app.p.tikzlabel{i},'$') 
                                    legend = [legend,sep,'$',strrep(app.p.tikzlabel{i},'$',''),'=',data{i,k},'$']; %#ok<AGROW>
                                else
                                    legend = [legend,sep,app.p.tikzlabel{i},'=',data{i,k}]; %#ok<AGROW>
                                end
                            end
                        else
                            legend = [legend,sep,app.label{i},'=',data{i,k}]; %#ok<AGROW>
                        end
                        sep = ', ';
                    end
                end
                if isLabel(end)
                    endData = strsplit(data{end,k},'-');
                    if length(endData)==2
                        if isfield(app.p,'errorlabel') && isfield(app.p.errorlabel,endData{1}) && isfield(app.p.errorlabel,endData{2})
                            legend = [app.p.errorlabel.(endData{1}),'/',app.p.errorlabel.(endData{2}),sep,legend]; %#ok<AGROW>
                        elseif isfield(app.p,'errorlabel') && isfield(app.p.errorlabel,endData{1})
                            legend = [app.p.errorlabel.(endData{1}),'/',endData{2},sep,legend]; %#ok<AGROW>
                        elseif isfield(app.p,'errorlabel') && isfield(app.p.errorlabel,endData{2})
                            legend = [endData{1},'/',app.p.errorlabel.(endData{2}),sep,legend]; %#ok<AGROW>
                        else
                            legend = [endData{1},'/',endData{2},sep,legend]; %#ok<AGROW>
                        end
                    else
                        if isfield(app.p,'errorlabel') && isfield(app.p.errorlabel,data{end,k})
                            legend = [app.p.errorlabel.(data{end,k}),sep,legend]; %#ok<AGROW>
                        else
                            legend = [data{end,k},sep,legend]; %#ok<AGROW>
                        end
                    end
                end
                lines{j} = ['  \addlegendentry{',legend,'} %auto entry = on'];
            end
        end
        
        % determine remaining options for current dropdown menue.
        function result = remainingOptions(app,field)
            allfields = app.dropDownNames;
            logic = zeros(size(app.files));
            for i = 1:size(logic,2)
                selected = app.([allfields{i},'DropDown']).Value;
                if ~isempty(selected) && ~strcmp(allfields{i},field)
                    logic(:,i) = strcmp(app.files(:,i),selected);
                else
                    logic(:,i)=1;
                end
            end
            result = unique(app.files(all(logic,2),find(strcmp(allfields,field))));
            if length(result)>1
                result = [{''};result];
            end
        end
        
        % set drop down menues to starting state
        function resetDropDown(app)
            for i = 1 : numel(app.dropDownNames)
                if ~isempty(app.files) && size(app.files,2)>=i
                    fields = unique(app.files(:,i));
                else
                    fields = [];
                end
                if length(fields)==1 %#ok<ISCL>
                    app.([app.dropDownNames{i},'DropDown']).Items = fields;
                else
                    app.([app.dropDownNames{i},'DropDown']).Items = {};
                end
            end
            app.loadFile;
        end
        
        % determine single drop down options implied by choosen options
        function filterDropDown(app)
            allfields = app.dropDownNames;
            logic = zeros(size(app.files));
            for i = 1:size(logic,2)
                selected = app.([allfields{i},'DropDown']).Value;
                if ~isempty(selected)
                    logic(:,i) = strcmp(app.files(:,i),selected);
                else
                    logic(:,i)=1;
                end
            end
            for i = 1:size(logic,2)
                selected = app.([allfields{i},'DropDown']).Value;
                if isempty(selected)
                    fields = unique(app.files(all(logic,2),i));
                    if length(fields)==1 %#ok<ISCL>
                        app.([app.dropDownNames{i},'DropDown']).Items = fields;
                    end
                end
            end
            
        end
        
        % read text file into cell array savely for unix and pc
        function lines = readFile2lines(~,filename)
            lines = fileread(filename);
            lines = regexp(lines, '\r?\n', 'split');
            if isempty(lines{end})
                lines(end) = [];
            end
        end

        % save data to *dat file
        function filenameData = saveData(app,data,label,dof)
            if ~exist(fullfile(pwd,'data'),'dir')
                mkdir('data');
            end
            filenameData = [app.generateFilename,'_',label,'--',dof,'.dat'];
            save(fullfile('data',filenameData),'data','-ascii','-double','-tabs');
            removeLevel = app.removelvlEditField.Value;
            indMinDoF = find(data(:,1)>=app.minDoFEditField.Value,1,'first');
            if indMinDoF>1 || removeLevel>0
                lines = app.readFile2lines(fullfile('data',filenameData));
                for i = 1 : indMinDoF-1
                    lines{i} = ['%',lines{i}];
                end
                for i = 0 : removeLevel-1
                    lines{end-i} = ['%',lines{end-i}];
                end
                writelines(lines,fullfile('data',filenameData));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set system specific default paths for latex
            if ismac
                app.latexfolderEditField.Value = '/Library/TeX/texbin/pdflatex';
            elseif isunix
                app.latexfolderEditField.Value = 'pdflatex';
            elseif ispc
                app.latexfolderEditField.Value = '';
            end
        end

        % Value changed function: pathEditField
        function pathEditFieldValueChanged(app, event)
            app.checkFolder;
        end

        % Value changed function: graphListBox
        function graphListBoxValueChanged(app, event)
            app.currentGraph = app.graphListBox.Value;
        end

        % Button pushed function: baseButton
        function baseButtonPushed(app, event)
            if ~isempty(app.currentGraph)
                app.currentBase = app.currentGraph;
            end
        end

        % Button pushed function: efficiencyButton
        function efficiencyButtonPushed(app, event)
            if isempty(app.currentEffNominator) || isempty(app.currentEffDenominator)
                return;
            end
            estimatedError = [app.p.level(1:end).(app.currentEffNominator)];
            error = [app.p.level(1:end).(app.currentEffDenominator)];
            eff = (estimatedError./error)';
            dof = [app.p.level(1:end).(app.currentBase)];
            figure(app.fignoEditField.Value);
            if ~isempty(app.legendEditField.Value)
                name = app.legendEditField.Value;
            elseif isfield(app.p,'errorlabel') && isfield(app.p.errorlabel,app.currentEffNominator) && isfield(app.p.errorlabel,app.currentEffDenominator)
                name = [app.p.errorlabel.(app.currentEffNominator),'/',app.p.errorlabel.(app.currentEffDenominator)];
            elseif isfield(app.p,'errorlabel') && isfield(app.p.errorlabel,app.currentEffNominator)
                name = [app.p.errorlabel.(app.currentEffNominator),'/',app.currentEffDenominator];
            elseif isfield(app.p,'errorlabel') && isfield(app.p.errorlabel,app.currentEffDenominator)
                name = [app.currentEffNominator,'/',app.p.errorlabel.(app.currentEffDenominator)];
            else
                name = [app.currentEffNominator,'/',app.currentEffDenominator];
            end
            marker = 'x';
            linestyle = '-';
            removeLevel = app.removelvlEditField.Value;
            start = find(dof>=app.minDoFEditField.Value,1,'first');
            if isfield(app.p,'draw')
                name = app.p.draw.name;
                marker = app.p.draw.marker;
                linestyle = app.p.draw.linestyle;
                c = app.p.draw.color;
                if strcmpi(app.currentBase,'nrDoF')
                    semilogx(dof(start:end-removeLevel),eff(start:end-removeLevel),'DisplayName',name,'Marker',marker,'LineStyle',linestyle,'Color',c);
                else
                    plot(dof(start:end-removeLevel),eff(start:end-removeLevel),'DisplayName',name,'Marker',marker,'LineStyle',linestyle,'Color',c);
                end
            else
                if strcmpi(app.currentBase,'nrDoF')
                    semilogx(dof(start:end-removeLevel),eff(start:end-removeLevel),'DisplayName',name,'Marker',marker,'LineStyle',linestyle);
                else
                    plot(dof(start:end-removeLevel),eff(start:end-removeLevel),'DisplayName',name,'Marker',marker,'LineStyle',linestyle);
                end
            end
            L = legend;
            if contains(name,'$')
                set(L, 'Interpreter', 'latex', 'Visible', 'on');
            else
                set(L, 'Visible', 'on');
            end
            hold on
        end

        % Button pushed function: tabularButton
        function tabularButtonPushed(app, event)
            if isempty(app.currentTable)
                return;
            end
            errorStr = app.currentTable;
            dof = [app.p.level(1:end).nrDoF]';
            error = zeros(numel(dof),length(errorStr));
            for J = 1 : length(errorStr)
                error(:,J) = [app.p.level(1:end).(errorStr{J})];
            end
            tab = '  ';
            tab2 = '    ';
            if isfield(app.p,'errorlabel')
                for J = 1 : length(errorStr)
                    if isfield(app.p.errorlabel,errorStr{J})
                        errorStr{J} = app.p.errorlabel.(errorStr{J});
                    end
                end
            end
            
            disp('tabular in DoF --> ')
            disp(' ')
            disp('\begin{table}[htp]')
            disp([tab,'\begin{tabular}{|r|',repmat('lr|',1,size(error,2)),'}'])
            str = [];
            for j = 1 : size(error,2)
                str = [str,' & ',errorStr{j},' & ','order']; %#ok<AGROW>
            end
            disp([tab2,'\hline'])
            disp([tab2,'DoF',str,' \\ \hline'])
            for curLvl = 1 : length(dof)
                str = [];
                for j = 1 : size(error,2)
                    if curLvl == 1
                        str = [str,' & $',num2str(error(curLvl,j),'%1.2e'),'}$ & ','--']; %#ok<AGROW>
                    else
                        eff = (log(error(curLvl-1,j)) - log(error(curLvl,j))) / ...
                            (log(dof(curLvl-1)) - log(dof(curLvl)));
                        str = [str,' & $',num2str(error(curLvl,j),'%1.2e'),'}$ & $',num2str(eff,'%1.1f'),'$']; %#ok<AGROW>
                    end
                end
                str = regexprep(str,'e-0','\\times 10^{-');
                str = regexprep(str,'e-','\\times 10^{-');
                str = regexprep(str,'e\+00','\\times 10^{0');
                str = regexprep(str,'e\+0','\\times 10^{');
                str = regexprep(str,'e\+','\\times 10^{');
                disp([tab2,'$',num2str(dof(curLvl)),'$ ',str,' \\'])
            end
            disp([tab2,'\hline'])
            disp([tab,'\end{tabular}'])
            disp([tab,'\caption{caption}'])
            disp([tab,'\label{tab:}'])
            disp('\end{table}')
            disp(' ')
            disp(' ')
            
            disp('tabular for (assumed) uniform refinement --> ')
            disp(' ')
            disp('\begin{table}[htp]')
            disp([tab,'\begin{tabular}{|r|',repmat('lr|',1,size(error,2)),'}'])
            str = [];
            for j = 1 : size(error,2)
                str = [str,' & ',errorStr{j},' & ','order']; %#ok<AGROW>
            end
            disp([tab2,'\hline'])
            disp([tab2,'level',str,' \\ \hline'])
            for curLvl = 1 : length(dof)
                str = [];
                for j = 1 : size(error,2)
                    if curLvl == 1
                        str = [str,' & $',num2str(error(curLvl,j),'%1.2e'),'}$ & ','--']; %#ok<AGROW>
                    else
                        eff = (log(error(curLvl-1,j)) - log(error(curLvl,j))) / log(2); %h_{j+1}=h_j/2
                        str = [str,' & $',num2str(error(curLvl,j),'%1.2e'),'}$ & $',num2str(eff,'%1.1f'),'$']; %#ok<AGROW>
                    end
                end
                str = regexprep(str,'e-0','\\times 10^{-');
                str = regexprep(str,'e-','\\times 10^{-');
                str = regexprep(str,'e\+00','\\times 10^{0');
                str = regexprep(str,'e\+0','\\times 10^{');
                str = regexprep(str,'e\+','\\times 10^{');
                if dof(1) <= 9
                    disp([tab2,'$',num2str(curLvl-1),'$',str,' \\'])
                elseif dof(1) <= 25
                    disp([tab2,'$',num2str(curLvl),'$',str,' \\'])
                elseif dof(1) <= 81+64
                    disp([tab2,'$',num2str(curLvl),'$',str,' \\'])
                else
                    disp([tab2,'$',num2str(curLvl),'$',str,' \\'])
                end
            end
            disp([tab2,'\hline'])
            disp([tab,'\end{tabular}'])
            disp([tab,'\caption{caption}'])
            disp([tab,'\label{tab:}'])
            disp('\end{table}')
            disp(' ')
            disp(' ')
        end

        % Button pushed function: TiKzButton
        function TiKzButtonPushed(app, event)
            if isempty(app.p)
                return;
            end
            % save data
            error = [app.p.level(1:end).(app.currentGraph)];
            dof = [app.p.level(1:end).(app.currentBase)];
            tab = [dof(:),error(:)];
            filenameData = app.saveData(tab,app.currentGraph,app.currentBase);
            % create and compile latex
            filenameLaTeX = [app.filenameEditField.Value,num2str(app.fignoEditField.Value),'.tex'];
            if ~exist(filenameLaTeX,'file')
                lines = app.readFile2lines('plotAppTikZ.tex');
            else
                lines = app.readFile2lines(filenameLaTeX);
            end
            % insert plot
            indEnd = numel(lines);
            k = find(contains(lines,'\end'));
            j = indEnd - k(end-2) + 1;
            for i = 0 :-1 :-j
                lines{indEnd+i+7} = lines{indEnd+i};
            end
            lines{end-j-6} = '';
            lines{end-j-5} = ['  %% ',app.currentGraph];
            lines{end-j-4} = '  \addplot';
            lines{end-j-3} = ['  table {','data/',filenameData,'};'];
            if ~isempty(app.legendEditField.Value)
                lines{end-j-2} = ['  \addlegendentry{',app.legendEditField.Value,'} %auto entry = off'];
            else
                lines{end-j-2} = ['  \addlegendentry{',app.currentGraph,'} %auto entry = on'];
            end
            removeLevel = app.removelvlEditField.Value;
            indMinDoF = find(dof>=app.minDoFEditField.Value,1,'first');
            c = polyfit(log(dof(indMinDoF:end-removeLevel)),log(error(indMinDoF:end-removeLevel)),1);
            x = exp(log(dof(end-removeLevel))*0.8);
            y = exp(c(1)*log(dof(end-removeLevel))*0.8 + c(2));
            if app.TopButton.Value
                lines{end-j-1} = ['  \logLogSlopeTriangleTop{',num2str(x,2),'}{',num2str(y*1.5,2),'}{',num2str(-c(1),2),'}{1}{thick,gray};'];
                lines{end-j} = ['  %\logLogSlopeTriangleBottom{',num2str(x,2),'}{',num2str(y*0.75,2),'}{',num2str(-c(1),2),'}{1}{thick,gray};'];
            elseif app.BottomButton.Value
                lines{end-j-1} = ['  %\logLogSlopeTriangleTop{',num2str(x,2),'}{',num2str(y*1.5,2),'}{',num2str(-c(1),2),'}{1}{thick,gray};'];
                lines{end-j} = ['  \logLogSlopeTriangleBottom{',num2str(x,2),'}{',num2str(y*0.75,2),'}{',num2str(-c(1),2),'}{1}{thick,gray};'];
            else
                lines{end-j-1} = ['  %\logLogSlopeTriangleTop{',num2str(x,2),'}{',num2str(y*1.5,2),'}{',num2str(-c(1),2),'}{1}{thick,gray};'];
                lines{end-j} = ['  %\logLogSlopeTriangleBottom{',num2str(x,2),'}{',num2str(y*0.75,2),'}{',num2str(-c(1),2),'}{1}{thick,gray};'];
            end
            lines = app.tikzUpdateLegend(lines);
            writelines(lines,filenameLaTeX);
            if app.LaTeXCheckBox.Value
                system([app.latexfolderEditField.Value,' -interaction=nonstopmode -output-directory=','./',' ',filenameLaTeX]);
                system(['rm ',app.filenameEditField.Value,'.aux']);
                system(['rm ',app.filenameEditField.Value,'.log']);
            end
        end

        % Button pushed function: showButton
        function showButtonPushed(app, event)
            if isempty(app.p)
                return;
            end
            error = [app.p.level(1:end).(app.currentGraph)];
            dof = [app.p.level(1:end).(app.currentBase)];
            if ~isempty(app.legendEditField.Value)
                name = app.legendEditField.Value;
            elseif isfield(app.p,'errorlabel') && isfield(app.p.errorlabel,app.currentGraph)
                name = app.p.errorlabel.(app.currentGraph);
            else
                name = app.currentGraph;
            end
            if ~isempty(app.p)
                figure(app.fignoEditField.Value)
                marker = 'x';
                linestyle = '-';
                removeLevel = app.removelvlEditField.Value;
                start = find(dof>=app.minDoFEditField.Value,1,'first');
                if isfield(app.p,'draw')
                    name = app.p.draw.name;
                    marker = app.p.draw.marker;
                    linestyle = app.p.draw.linestyle;
                    c = app.p.draw.color;
                    loglog(dof(start:end-removeLevel),error(start:end-removeLevel),'DisplayName',name,'Marker',marker,'LineStyle',linestyle,'Color',c);
                else
                    loglog(dof(start:end-removeLevel),error(start:end-removeLevel),'DisplayName',name,'Marker',marker,'LineStyle',linestyle);
                end
            end
            hold on
            if app.gridoffCheckBox.Value == 1
                grid off
            else
                grid on
            end
            L = legend;
            if contains(name,'$')
                set(L, 'Interpreter', 'latex', 'Visible', 'on');
            else
                set(L, 'Visible', 'on');
            end

            minDoF = app.minDoFEditField.Value;
            indx = find(dof>=minDoF,1,'first');
            if isempty(indx); indx = max(1,numel(dof)-1); end
            indx = min(indx, numel(dof)-1);
            dof = dof(:);
            error = error(:);
            rate = polyfit(log(dof(indx:end-removeLevel)),log(error(indx:end-removeLevel)),1);
            rate = num2str(abs(rate(1)));
            app.rateLabel.Text = ['rate: ',rate];
        end

        % Value changed function: tableListBox
        function tableListBoxValueChanged(app, event)
            app.currentTable = app.tableListBox.Value;
        end

        % Button pushed function: folderButton
        function folderButtonPushed(app, event)
            path = fem4matPath.resultFolder;
            selpath = uigetdir(path);   
            if selpath ~=0
                app.pathEditField.Value = selpath;
                app.checkFolder;
            end
        end

        % Value changed function: effNominatorListBox
        function effNominatorListBoxValueChanged(app, event)
            app.currentEffNominator = app.effNominatorListBox.Value;
        end

        % Value changed function: effDenominatorListBox
        function effDenominatorListBoxValueChanged(app, event)
            app.currentEffDenominator = app.effDenominatorListBox.Value;
        end

        % Button pushed function: TiKzefficiencyButton
        function TiKzefficiencyButtonPushed(app, event)
            if isempty(app.currentEffNominator) || isempty(app.currentEffDenominator)
                return;
            end
            estimatedError = [app.p.level(1:end).(app.currentEffNominator)];
            eff = [app.p.level(1:end).(app.currentEffDenominator)];
            eff = (estimatedError./eff)';
            dof = [app.p.level(1:end).(app.currentBase)];
            tab = [dof(:),eff(:)];
            filenameData = app.saveData(tab,[app.currentEffNominator,'-',app.currentEffDenominator],app.currentBase);
            filenameLaTeX = [app.filenameEditField.Value,num2str(app.fignoEditField.Value),'.tex'];
            if ~exist(filenameLaTeX,'file')
                lines = app.readFile2lines('plotAppTikZ.tex');
                lines = replace(lines,'loglogaxis','semilogxaxis');
                lines = replace(lines,'legend pos = south west','legend pos = north east');
            else
                lines = app.readFile2lines(filenameLaTeX);
            end
            indEnd = numel(lines);
            k = find(contains(lines,'\end'));
            j = indEnd - k(end-2) + 1;
            for i = 0 :-1 :-j
                lines{indEnd+i+5} = lines{indEnd+i};
            end
            lines{end-j-4} = '';
            lines{end-j-3} = ['  %% ',app.currentEffNominator,'-',app.currentEffDenominator];
            lines{end-j-2} = '  \addplot';
            lines{end-j-1} = ['  table {','data/',filenameData,'};'];
            if ~isempty(app.legendEditField.Value)
                lines{end-j} = ['  \addlegendentry{',app.legendEditField.Value,'} %auto entry = off'];
            else
                if isfield(app.p,'errorlabel') && isfield(app.p.errorlabel,app.currentEffNominator) && isfield(app.p.errorlabel,app.currentEffDenominator)
                    name = [app.p.errorlabel.(app.currentEffNominator),'/',app.p.errorlabel.(app.currentEffDenominator)];
                elseif isfield(app.p,'errorlabel') && isfield(app.p.errorlabel,app.currentEffNominator)
                    name = [app.p.errorlabel.(app.currentEffNominator),'/',app.currentEffDenominator];
                elseif isfield(app.p,'errorlabel') && isfield(app.p.errorlabel,app.currentEffDenominator)
                    name = [app.currentEffNominator,'/',app.p.errorlabel.(app.currentEffDenominator)];
                else
                    name = [app.currentEffNominator,'/',app.currentEffDenominator];
                end
                lines{end-j} = ['  \addlegendentry{',name,'} %auto entry = on'];
            end
            lines = app.tikzUpdateLegend(lines);
            writelines(lines,filenameLaTeX);
            if app.LaTeXCheckBox.Value
                system([app.latexfolderEditField.Value,' -interaction=nonstopmode -output-directory=','./',' ',filenameLaTeX]);
                system(['rm ',app.filenameEditField.Value,'.aux']);
                system(['rm ',app.filenameEditField.Value,'.log']);
            end
        end

        % Drop down opening function: problemDropDown
        function problemDropDownOpening(app, event)
            if ~isempty(app.files)
                app.problemDropDown.Items = app.remainingOptions('problem');
                app.loadFile;
            end
        end

        % Drop down opening function: feDropDown
        function feDropDownOpening(app, event)
            if ~isempty(app.files)
                app.feDropDown.Items = app.remainingOptions('fe');
                app.loadFile;
            end
        end

        % Drop down opening function: refineDropDown
        function refineDropDownOpening(app, event)
            if ~isempty(app.files)
                app.refineDropDown.Items = app.remainingOptions('refine');
                app.loadFile;
            end
        end

        % Drop down opening function: estimatorDropDown
        function estimatorDropDownOpening(app, event)
            if ~isempty(app.files)
                app.estimatorDropDown.Items = app.remainingOptions('estimator');
                app.loadFile;
            end
        end

        % Drop down opening function: solverDropDown
        function solverDropDownOpening(app, event)
            if ~isempty(app.files)
                app.solverDropDown.Items = app.remainingOptions('solver');
                app.loadFile;
            end
        end

        % Drop down opening function: param1DropDown
        function param1DropDownOpening(app, event)
            if ~isempty(app.files)
                app.param1DropDown.Items = app.remainingOptions('param1');
                app.loadFile;
            end
        end

        % Drop down opening function: param2DropDown
        function param2DropDownOpening(app, event)
            if ~isempty(app.files)
                app.param2DropDown.Items = app.remainingOptions('param2');
                app.loadFile;
            end
        end

        % Drop down opening function: maxNrDoFDropDown
        function maxNrDoFDropDownOpening(app, event)
            if ~isempty(app.files)
                app.maxNrDoFDropDown.Items = app.remainingOptions('maxNrDoF');
                app.loadFile;
            end
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.resetDropDown();
        end

        % Value changed function: problemDropDown
        function problemDropDownValueChanged(app, event)
            app.filterDropDown;
            app.loadFile;
        end

        % Value changed function: feDropDown
        function feDropDownValueChanged(app, event)
            app.filterDropDown;
            app.loadFile;
        end

        % Value changed function: refineDropDown
        function refineDropDownValueChanged(app, event)
            app.filterDropDown;
            app.loadFile;
        end

        % Value changed function: estimatorDropDown
        function estimatorDropDownValueChanged(app, event)
            app.filterDropDown;
            app.loadFile;
        end

        % Value changed function: solverDropDown
        function solverDropDownValueChanged(app, event)
            app.filterDropDown;
            app.loadFile;
        end

        % Value changed function: param1DropDown
        function param1DropDownValueChanged(app, event)
            app.filterDropDown;
            app.loadFile;
        end

        % Value changed function: param2DropDown
        function param2DropDownValueChanged(app, event)
            app.filterDropDown;
            app.loadFile;
        end

        % Value changed function: maxNrDoFDropDown
        function maxNrDoFDropDownValueChanged(app, event)
            app.filterDropDown;
            app.loadFile;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.filenamePanel = uipanel(app.UIFigure);
            app.filenamePanel.Title = 'filename';
            app.refineDropDownLabel = uilabel(app.filenamePanel);
            app.refineDropDownLabel.Text = 'refine';
            app.refineDropDown = uidropdown(app.filenamePanel);
            app.refineDropDown.Items = {};
            app.refineDropDown.DropDownOpeningFcn = createCallbackFcn(app, @refineDropDownOpening, true);
            app.refineDropDown.ValueChangedFcn = createCallbackFcn(app, @refineDropDownValueChanged, true);
            app.refineDropDown.Value = {};
            app.feDropDownLabel = uilabel(app.filenamePanel);
            app.feDropDownLabel.Text = 'fe';
            app.feDropDown = uidropdown(app.filenamePanel);
            app.feDropDown.Items = {};
            app.feDropDown.DropDownOpeningFcn = createCallbackFcn(app, @feDropDownOpening, true);
            app.feDropDown.ValueChangedFcn = createCallbackFcn(app, @feDropDownValueChanged, true);
            app.feDropDown.Value = {};
            app.problemDropDownLabel = uilabel(app.filenamePanel);
            app.problemDropDownLabel.Text = 'problem';
            app.problemDropDown = uidropdown(app.filenamePanel);
            app.problemDropDown.Items = {};
            app.problemDropDown.DropDownOpeningFcn = createCallbackFcn(app, @problemDropDownOpening, true);
            app.problemDropDown.ValueChangedFcn = createCallbackFcn(app, @problemDropDownValueChanged, true);
            app.problemDropDown.Value = {};
            app.estimatorDropDownLabel = uilabel(app.filenamePanel);
            app.estimatorDropDownLabel.Text = 'estimator';
            app.estimatorDropDown = uidropdown(app.filenamePanel);
            app.estimatorDropDown.Items = {};
            app.estimatorDropDown.DropDownOpeningFcn = createCallbackFcn(app, @estimatorDropDownOpening, true);
            app.estimatorDropDown.ValueChangedFcn = createCallbackFcn(app, @estimatorDropDownValueChanged, true);
            app.estimatorDropDown.Value = {};
            app.param1DropDownLabel = uilabel(app.filenamePanel);
            app.param1DropDownLabel.Text = 'param1';
            app.param1DropDown = uidropdown(app.filenamePanel);
            app.param1DropDown.Items = {};
            app.param1DropDown.DropDownOpeningFcn = createCallbackFcn(app, @param1DropDownOpening, true);
            app.param1DropDown.ValueChangedFcn = createCallbackFcn(app, @param1DropDownValueChanged, true);
            app.param1DropDown.Value = {};
            app.param2DropDownLabel = uilabel(app.filenamePanel);
            app.param2DropDownLabel.Text = 'param2';
            app.param2DropDown = uidropdown(app.filenamePanel);
            app.param2DropDown.Items = {};
            app.param2DropDown.DropDownOpeningFcn = createCallbackFcn(app, @param2DropDownOpening, true);
            app.param2DropDown.ValueChangedFcn = createCallbackFcn(app, @param2DropDownValueChanged, true);
            app.param2DropDown.Value = {};
            app.solverDropDownLabel = uilabel(app.filenamePanel);
            app.solverDropDownLabel.Text = 'solver';
            app.solverDropDown = uidropdown(app.filenamePanel);
            app.solverDropDown.Items = {};
            app.solverDropDown.DropDownOpeningFcn = createCallbackFcn(app, @solverDropDownOpening, true);
            app.solverDropDown.ValueChangedFcn = createCallbackFcn(app, @solverDropDownValueChanged, true);
            app.solverDropDown.Value = {};
            app.maxNrDoFDropDownLabel = uilabel(app.filenamePanel);
            app.maxNrDoFDropDownLabel.Text = 'maxNrDoF';
            app.maxNrDoFDropDown = uidropdown(app.filenamePanel);
            app.maxNrDoFDropDown.Items = {};
            app.maxNrDoFDropDown.DropDownOpeningFcn = createCallbackFcn(app, @maxNrDoFDropDownOpening, true);
            app.maxNrDoFDropDown.ValueChangedFcn = createCallbackFcn(app, @maxNrDoFDropDownValueChanged, true);
            app.maxNrDoFDropDown.Value = {};
            app.resetButton = uibutton(app.filenamePanel, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.showPanel = uipanel(app.UIFigure);
            app.showPanel.Title = 'show';
            app.showButton = uibutton(app.showPanel, 'push');
            app.showButton.ButtonPushedFcn = createCallbackFcn(app, @showButtonPushed, true);
            app.showButton.Text = 'show';
            app.baseButton = uibutton(app.showPanel, 'push');
            app.baseButton.ButtonPushedFcn = createCallbackFcn(app, @baseButtonPushed, true);
            app.baseButton.Text = 'base';
            app.efficiencyButton = uibutton(app.showPanel, 'push');
            app.efficiencyButton.ButtonPushedFcn = createCallbackFcn(app, @efficiencyButtonPushed, true);
            app.efficiencyButton.Text = 'efficiency';
            app.tabularButton = uibutton(app.showPanel, 'push');
            app.tabularButton.ButtonPushedFcn = createCallbackFcn(app, @tabularButtonPushed, true);
            app.tabularButton.Text = 'tabular';
            app.TiKzButton = uibutton(app.showPanel, 'push');
            app.TiKzButton.ButtonPushedFcn = createCallbackFcn(app, @TiKzButtonPushed, true);
            app.TiKzButton.Text = 'TiKz';
            app.TiKzefficiencyButton = uibutton(app.showPanel, 'push');
            app.TiKzefficiencyButton.ButtonPushedFcn = createCallbackFcn(app, @TiKzefficiencyButtonPushed, true);
            app.TiKzefficiencyButton.Text = 'TiKz efficiency';
            app.LoadPanel = uipanel(app.UIFigure);
            app.LoadPanel.Title = 'Load';
            app.pathEditFieldLabel = uilabel(app.LoadPanel);
            app.pathEditFieldLabel.Text = 'path';
            app.pathEditField = uieditfield(app.LoadPanel, 'text');
            app.pathEditField.ValueChangedFcn = createCallbackFcn(app, @pathEditFieldValueChanged, true);
            app.textDoFLabel = uilabel(app.LoadPanel);
            app.textDoFLabel.Text = 'textDoF';
            app.loadedLampLabel = uilabel(app.LoadPanel);
            app.loadedLampLabel.Text = 'loaded';
            app.loadedLamp = uilamp(app.LoadPanel);
            app.folderButton = uibutton(app.LoadPanel, 'push');
            app.folderButton.ButtonPushedFcn = createCallbackFcn(app, @folderButtonPushed, true);
            app.folderButton.Text = 'folder';
            app.fem4matLabel = uilabel(app.LoadPanel);
            app.fem4matLabel.Text = 'fem4mat';
            app.figurePanel = uipanel(app.UIFigure);
            app.figurePanel.Title = 'figure';
            app.gridoffCheckBox = uicheckbox(app.figurePanel);
            app.gridoffCheckBox.Text = 'grid off';
            app.gridoffCheckBox.Value = true;
            app.legendEditFieldLabel = uilabel(app.figurePanel);
            app.legendEditFieldLabel.Text = 'legend';
            app.legendEditField = uieditfield(app.figurePanel, 'text');
            app.rateLabel = uilabel(app.figurePanel);
            app.rateLabel.Text = 'rate';
            app.minDoFEditFieldLabel = uilabel(app.figurePanel);
            app.minDoFEditFieldLabel.Text = 'minDoF';
            app.minDoFEditField = uieditfield(app.figurePanel, 'numeric');
            app.minDoFEditField.Limits = [0 Inf];
            app.minDoFEditField.ValueDisplayFormat = '%.0f';
            app.removelvlEditFieldLabel = uilabel(app.figurePanel);
            app.removelvlEditFieldLabel.Text = 'remove lvl';
            app.removelvlEditField = uieditfield(app.figurePanel, 'numeric');
            app.removelvlEditField.Limits = [0 Inf];
            app.removelvlEditField.ValueDisplayFormat = '%.0f';
            app.fignoEditFieldLabel = uilabel(app.figurePanel);
            app.fignoEditFieldLabel.Text = 'fig no';
            app.fignoEditField = uieditfield(app.figurePanel, 'numeric');
            app.fignoEditField.Limits = [1 Inf];
            app.fignoEditField.ValueDisplayFormat = '%.0f';
            app.fignoEditField.Value = 1;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.graphTab = uitab(app.TabGroup);
            app.graphTab.Title = 'graph';
            app.ListBoxLabel = uilabel(app.graphTab);
            app.ListBoxLabel.Text = '';
            app.graphListBox = uilistbox(app.graphTab);
            app.graphListBox.Items = {};
            app.graphListBox.ValueChangedFcn = createCallbackFcn(app, @graphListBoxValueChanged, true);
            app.graphListBox.Value = {};
            app.tableTab = uitab(app.TabGroup);
            app.tableTab.Title = 'table';
            app.Label = uilabel(app.tableTab);
            app.Label.Text = '';
            app.tableListBox = uilistbox(app.tableTab);
            app.tableListBox.Items = {};
            app.tableListBox.Multiselect = 'on';
            app.tableListBox.ValueChangedFcn = createCallbackFcn(app, @tableListBoxValueChanged, true);
            app.tableListBox.Value = {};
            app.efficiencyTab = uitab(app.TabGroup);
            app.efficiencyTab.Title = 'efficiency';
            app.effNominatorListBox = uilistbox(app.efficiencyTab);
            app.effNominatorListBox.Items = {};
            app.effNominatorListBox.ValueChangedFcn = createCallbackFcn(app, @effNominatorListBoxValueChanged, true);
            app.effNominatorListBox.Value = {};
            app.effDenominatorListBox = uilistbox(app.efficiencyTab);
            app.effDenominatorListBox.Items = {};
            app.effDenominatorListBox.ValueChangedFcn = createCallbackFcn(app, @effDenominatorListBoxValueChanged, true);
            app.effDenominatorListBox.Value = {};
            app.effDenominatorListBoxLabel = uilabel(app.efficiencyTab);
            app.effDenominatorListBoxLabel.Text = 'nominator';
            app.effNominatorListBoxLabel = uilabel(app.efficiencyTab);
            app.effNominatorListBoxLabel.Text = 'denominator';
            app.TiKzTab = uitab(app.TabGroup);
            app.TiKzTab.Title = 'TiKz';
            app.LaTeXCheckBox = uicheckbox(app.TiKzTab);
            app.LaTeXCheckBox.Text = 'LaTeX';
            app.filenameEditFieldLabel = uilabel(app.TiKzTab);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.TiKzTab, 'text');
            app.filenameEditField.Value = 'figure';
            app.latexfolderEditFieldLabel = uilabel(app.TiKzTab);
            app.latexfolderEditFieldLabel.Text = 'latex folder';
            app.latexfolderEditField = uieditfield(app.TiKzTab, 'text');
            app.TriangleButtonGroup = uibuttongroup(app.TiKzTab);
            app.TriangleButtonGroup.Title = 'Triangle';
            app.NoneButton = uiradiobutton(app.TriangleButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.TopButton = uiradiobutton(app.TriangleButtonGroup);
            app.TopButton.Text = 'Top';
            app.BottomButton = uiradiobutton(app.TriangleButtonGroup);
            app.BottomButton.Text = 'Bottom';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end